classdef Bionic_RF_Radar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BionicRFRadarUIFigure      matlab.ui.Figure
        ExportDataButton           matlab.ui.control.Button
        VitalSignsPanel            matlab.ui.container.Panel
        EditField_2                matlab.ui.control.NumericEditField
        RespirationRateRPMLabel    matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        HeartRateBPMLabel          matlab.ui.control.Label
        RunSpectralAnalysisButton  matlab.ui.control.Button
        SpectralAnalysisPanel      matlab.ui.container.Panel
        RespirationFFTAxes         matlab.ui.control.UIAxes
        HeartRateFFTAxes           matlab.ui.control.UIAxes
        LiveSignalMonitoringPanel  matlab.ui.container.Panel
        ProcessedCSISignalAxes     matlab.ui.control.UIAxes
        RawSignalAxes              matlab.ui.control.UIAxes
        RFSensorConnectionPanel    matlab.ui.container.Panel
        DISCONNECTEDStatusLabel    matlab.ui.control.Label
        ConnectionLamp             matlab.ui.control.Lamp
        DISCONNECTButton           matlab.ui.control.Button
        CONNECTButton              matlab.ui.control.Button
        BaudRateLabel              matlab.ui.control.Label
        BaudRateFieldEditField     matlab.ui.control.NumericEditField
        DropDown                   matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        SerialPortLabel            matlab.ui.control.Label
        ContactlessVitalSignDetectionviaWiFiCSILabel  matlab.ui.control.Label
        BionicRFRadarLabel         matlab.ui.control.Label
    end

    
  properties (Access = private)
    SerialObj           % كائن الاتصال التسلسلي
    CSIBuffer = []      % بافر لتخزين حزم CSI المكتسبة
    Fs = 20             % معدل أخذ العينات (20 Hz)
    WindowSize = 400    % حجم نافذة البيانات (20 ثانية)
  end
  methods (Access = private)

    function parseCSIPacket(app, src, ~)
        try
            if src.NumBytesAvailable > 0
                lineStr = readline(src);
                lineStr = char(lineStr);
                
                dataIdx = strfind(lineStr, 'DATA:');
                if ~isempty(dataIdx)
                    rawNumbersStr = lineStr(dataIdx + 5 : end);
                    nums = str2num(rawNumbersStr); %#ok<ST2NM>
                    
                    if ~isempty(nums) && mod(length(nums), 2) == 0
                        realParts = nums(1:2:end-1);
                        imagParts = nums(2:2:end);
                        complexCSI = realParts + 1i * imagParts;
                        
                        amplitudes = abs(complexCSI);
                        
                        app.CSIBuffer = [app.CSIBuffer; amplitudes];
                        
                        if size(app.CSIBuffer, 1) > app.WindowSize
                            app.CSIBuffer(1:end-app.WindowSize, :) = [];
                        end
                        
                        if size(app.CSIBuffer, 1) >= 20
                            app.processCSIData();
                        end
                    end
                end
            end
        catch
            % ignore incomplete lines
        end
    end

   function processCSIData(app)
    if isempty(app.CSIBuffer) || size(app.CSIBuffer, 1) < 20
        return; 
    end
    
    % 1. بناء محور الزمن بالثواني بناءً على عدد العينات ومعدل Fs = 20 Hz
    N_samples = size(app.CSIBuffer, 1);
    t = (0:N_samples-1) / app.Fs; % الزمن بالثواني
    
    % 2. Dimensionality Reduction using PCA (المكون الرئيسي الأول PC1)
    data = app.CSIBuffer;
    [~, score] = pca(data);
    pc1 = score(:, 1);
    
    % رسم الإشارة الخام بالنسبة للزمن (بالثواني)
    plot(app.RawSignalAxes, t, pc1, 'Color', [0 0.45 0.74], 'LineWidth', 1.2);
    grid(app.RawSignalAxes, 'on');
    title(app.RawSignalAxes, 'Raw CSI Stream (PC1)');
    xlabel(app.RawSignalAxes, 'Time (s)');
    ylabel(app.RawSignalAxes, 'Amplitude (a.u.)');
    
    % 3. Static Clutter Removal (إزالة الانحراف الثابت)
    pc1_clean = pc1 - movmean(pc1, app.Fs * 2);
    
    % 4. IIR Bandpass Filtering - استخلاص موجة التنفس وموجة القلب زمنياً
    [b_resp, a_resp] = butter(2, [0.15 0.50]/(app.Fs/2), 'bandpass');
    resp_signal = filtfilt(b_resp, a_resp, pc1_clean);
    
    [b_heart, a_heart] = butter(2, [0.80 2.00]/(app.Fs/2), 'bandpass');
    heart_signal = filtfilt(b_heart, a_heart, pc1_clean);
    
    % رسم الإشارتين الزمنيتين المفلترتين على المحور الأيمن (التنفس بالأزرق والنبض بالأحمر)
    plot(app.ProcessedCSISignalAxes, t, resp_signal, 'b-', 'LineWidth', 1.2);
    hold(app.ProcessedCSISignalAxes, 'on');
    plot(app.ProcessedCSISignalAxes, t, heart_signal, 'r-', 'LineWidth', 1.0);
    hold(app.ProcessedCSISignalAxes, 'off');
    grid(app.ProcessedCSISignalAxes, 'on');
    title(app.ProcessedCSISignalAxes, 'Filtered Time-Domain Waveforms (Blue: Resp | Red: Heart)');
    xlabel(app.ProcessedCSISignalAxes, 'Time (s)');
    ylabel(app.ProcessedCSISignalAxes, 'Amplitude');
    
    % 5. Spectral Analysis (FFT)
    N = length(pc1_clean);
    f = (0:N-1)*(app.Fs/N);
    half_N = floor(N/2);
    
    fft_resp = abs(fft(resp_signal));
    plot(app.RespirationFFTAxes, f(1:half_N), fft_resp(1:half_N), 'b', 'LineWidth', 1.5);
    xlim(app.RespirationFFTAxes, [0.1 0.6]);
    grid(app.RespirationFFTAxes, 'on');
    title(app.RespirationFFTAxes, 'Respiration Spectrum (FFT)');
    xlabel(app.RespirationFFTAxes, 'Frequency (Hz)');
    ylabel(app.RespirationFFTAxes, 'Magnitude');
    
    fft_heart = abs(fft(heart_signal));
    plot(app.HeartRateFFTAxes, f(1:half_N), fft_heart(1:half_N), 'r', 'LineWidth', 1.5);
    xlim(app.HeartRateFFTAxes, [0.8 2.2]);
    grid(app.HeartRateFFTAxes, 'on');
    title(app.HeartRateFFTAxes, 'Heart Rate Spectrum (FFT)');
    xlabel(app.HeartRateFFTAxes, 'Frequency (Hz)');
    ylabel(app.HeartRateFFTAxes, 'Magnitude');
    
    % 6. حساب القيم الرقمية للنبض والتنفس
    [~, idx_resp] = max(fft_resp(1:half_N));
    resp_freq = f(idx_resp);
    rr_rpm = round(resp_freq * 60);
    
    [~, idx_heart] = max(fft_heart(1:half_N));
    heart_freq = f(idx_heart);
    hr_bpm = round(heart_freq * 60);
    
    % تحديث المؤشرات الرقمية
    if isprop(app, 'EditField_2')
        app.EditField_2.Value = double(rr_rpm);
    end
    if isprop(app, 'EditField')
        app.EditField.Value = double(hr_bpm);
    end
   end
  end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RunSpectralAnalysisButtonPushed(app, event)
          % Button pushed function: RunSpectralAnalysisButton
function RunSpectralAnalysisButtonPushed(app, event)
    % التحقق من وجود بيانات داخل البافر
    if isempty(app.CSIBuffer) || size(app.CSIBuffer, 1) < 10
        uialert(app.BionicRFRadarUIFigure, ...
            'No CSI data available yet. Connect and wait for data stream.', ...
            'Data Warning');
        return;
    end
    
    % استدعاء معالجة الإشارة
    app.processCSIData();
end
        end

        % Button down function: RespirationFFTAxes
        function RespirationFFTAxesButtonDown(app, event)
            
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
           try
    % قراءة اسم المنفذ وسرعة النقل بالأسماء الدقيقة لمكوناتك
    comPort = app.DropDown.Value;
    baudRate = app.BaudRateFieldEditField.Value;
    
    % فتح الاتصال التسلسلي مع المنفذ
    app.SerialObj = serialport(comPort, baudRate);
    % إعداد مقاطعة استقبال البيانات القادمة من ESP32
configureCallback(app.SerialObj, "terminator", @(src, event) app.parseCSIPacket(src, event));
    
    % تغيير حالة الواجهة لتدل على نجاح الاتصال
    app.ConnectionLamp.Color = 'g'; % تغيير لون اللمبة للأخضر
    app.DISCONNECTEDStatusLabel.Text = 'CONNECTED';
    app.DISCONNECTEDStatusLabel.FontColor = [0 0.8 0]; % لون النص أخضر
    
    % تعطيل زر الاتصال وتفعيل زر قطع الاتصال
    app.CONNECTButton.Enable = 'off';
    app.DISCONNECTButton.Enable = 'on';
    
catch ME
    % إظهار رسالة خطأ موجهة للنافذة الرئيسية بالتسمية الصحيحة
    uialert(app.BionicRFRadarUIFigure, ME.message, 'Connection Error');
end
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
        % إغلاق المنفذ التسلسلي وإلغاء الـ Callback
if ~isempty(app.SerialObj) && isvalid(app.SerialObj)
    configureCallback(app.SerialObj, "off"); % إيقاف استقبال البيانات
    delete(app.SerialObj); % إغلاق المنفذ
    app.SerialObj = [];
end

% تحديث شكل الواجهة
app.ConnectionLamp.Color = [0.65 0.65 0.65]; % رمادي
app.DISCONNECTEDStatusLabel.Text = 'DISCONNECTED';
app.DISCONNECTEDStatusLabel.FontColor = [0.5 0.5 0.5];

app.CONNECTButton.Enable = 'on';
app.DISCONNECTButton.Enable = 'off';
        end

        % Button pushed function: RunSpectralAnalysisButton
        function RunSpectralAnalysisButtonPushed2(app, event)
           % Button pushed function: RunSpectralAnalysisButton
function RunSpectralAnalysisButtonPushed(app, event)
    % 1. التأكد من وجود بيانات
    if ~isempty(app.CSIBuffer) && size(app.CSIBuffer, 1) >= 10
        % إعادة تشغيل خوارزميات الـ DSP والـ FFT فوراً وتحديث المحاور
        app.processCSIData();
        
        % إظهار تأكيد بصري بسيط على الواجهة
        title(app.RespirationFFTAxes, 'Respiration Spectrum (Refreshed)');
        title(app.HeartRateFFTAxes, 'Heart Rate Spectrum (Refreshed)');
    else
        % إذا لم تكن هناك بيانات مخزنة بعد
        uialert(app.BionicRFRadarUIFigure, ...
            'تنبيه: لا توجد بيانات CSI كافية في الذاكرة. يرجى الضغط على CONNECT أولاً لجمع البيانات.', ...
            'Spectral Analysis Warning');
    end
end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
     % Button pushed function: ExportDataButton
    % 1. Check if buffer has data
    if isempty(app.CSIBuffer)
        uialert(app.BionicRFRadarUIFigure, ...
            'No CSI data available for export. Please connect and stream data first.', ...
            'Export Warning');
        return;
    end
    
    % 2. Open file save dialog
    [file, path] = uiputfile('CSI_VitalSigns_Data.csv', 'Save CSI & Vital Signs Data');
    if isequal(file, 0) || isequal(path, 0)
        return; % User canceled
    end
    
    fullFilePath = fullfile(path, file);
    
    try
        % 3. Extract time-domain signals & DSP outputs
        data = app.CSIBuffer;
        N_samples = size(data, 1);
        t = ((0:N_samples-1) / app.Fs)'; % Time axis in seconds
        
        [~, score] = pca(data);
        pc1 = score(:, 1);
        pc1_clean = pc1 - movmean(pc1, app.Fs * 2);
        
        [b_resp, a_resp] = butter(2, [0.15 0.50]/(app.Fs/2), 'bandpass');
        resp_signal = filtfilt(b_resp, a_resp, pc1_clean);
        
        [b_heart, a_heart] = butter(2, [0.80 2.00]/(app.Fs/2), 'bandpass');
        heart_signal = filtfilt(b_heart, a_heart, pc1_clean);
        
        % 4. Aggregate data into table and write CSV
        exportTable = table(t, pc1, resp_signal, heart_signal, ...
            'VariableNames', {'Time_sec', 'Raw_PC1', 'Respiration_Signal', 'Heart_Signal'});
        
        writetable(exportTable, fullFilePath);
        
        % Success notification
        uialert(app.BionicRFRadarUIFigure, ...
            ['Data exported successfully to:' newline fullFilePath], ...
            'Export Success');
            
    catch ME
        uialert(app.BionicRFRadarUIFigure, ...
            ['Error saving file: ' ME.message], ...
            'Export Error');
    end


        end

        % Close request function: BionicRFRadarUIFigure
        function BionicRFRadarUIFigureCloseRequest(app, event)
% Code that executes when app is closed
    % 1. Stop serial callback and release COM port
    if ~isempty(app.SerialObj) && isvalid(app.SerialObj)
        configureCallback(app.SerialObj, "off");
        delete(app.SerialObj);
        app.SerialObj = [];
    end
    
    % 2. Delete the UI figure safely
    delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BionicRFRadarUIFigure = uifigure('Visible', 'off');
            app.BionicRFRadarUIFigure.Name = 'Bionic RFRadar';
            app.BionicRFRadarUIFigure.CloseRequestFcn = createCallbackFcn(app, @BionicRFRadarUIFigureCloseRequest, true);
            app.BionicRFRadarLabel = uilabel(app.BionicRFRadarUIFigure);
            app.BionicRFRadarLabel.Text = 'Bionic RF Radar';
            app.ContactlessVitalSignDetectionviaWiFiCSILabel = uilabel(app.BionicRFRadarUIFigure);
            app.ContactlessVitalSignDetectionviaWiFiCSILabel.Text = 'Contactless Vital Sign Detection via Wi-Fi CSI';
            app.RFSensorConnectionPanel = uipanel(app.BionicRFRadarUIFigure);
            app.RFSensorConnectionPanel.Title = 'RF Sensor Connection ';
            app.SerialPortLabel = uilabel(app.RFSensorConnectionPanel);
            app.SerialPortLabel.Text = 'Serial Port :';
            app.DropDownLabel = uilabel(app.RFSensorConnectionPanel);
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.RFSensorConnectionPanel);
            app.DropDown.Items = {'COM1', 'COM 2', 'COM 3', 'COM 4', 'COM 5', 'COM 6', 'COM 7', 'COM 8', 'COM 9', 'COM 10', 'COM11'};
            app.DropDown.Value = 'COM 3';
            app.BaudRateFieldEditField = uieditfield(app.RFSensorConnectionPanel, 'numeric');
            app.BaudRateFieldEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateFieldEditField.Value = 921600;
            app.BaudRateLabel = uilabel(app.RFSensorConnectionPanel);
            app.BaudRateLabel.Text = 'Baud Rate:';
            app.CONNECTButton = uibutton(app.RFSensorConnectionPanel, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.RFSensorConnectionPanel, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.ConnectionLamp = uilamp(app.RFSensorConnectionPanel);
            app.DISCONNECTEDStatusLabel = uilabel(app.RFSensorConnectionPanel);
            app.DISCONNECTEDStatusLabel.Text = 'DISCONNECTED';
            app.LiveSignalMonitoringPanel = uipanel(app.BionicRFRadarUIFigure);
            app.LiveSignalMonitoringPanel.Title = 'Live Signal Monitoring';
            app.RawSignalAxes = uiaxes(app.LiveSignalMonitoringPanel);
            title(app.RawSignalAxes, 'CSI Signal')
            xlabel(app.RawSignalAxes, 'Time in second (S)')
            ylabel(app.RawSignalAxes, 'Magnitude')
            zlabel(app.RawSignalAxes, 'Z')
            app.ProcessedCSISignalAxes = uiaxes(app.LiveSignalMonitoringPanel);
            title(app.ProcessedCSISignalAxes, 'Processed CSI Signal')
            xlabel(app.ProcessedCSISignalAxes, 'Time in second (S)')
            ylabel(app.ProcessedCSISignalAxes, 'Normalized Amplitude')
            zlabel(app.ProcessedCSISignalAxes, 'Z')
            app.SpectralAnalysisPanel = uipanel(app.BionicRFRadarUIFigure);
            app.SpectralAnalysisPanel.Title = 'Spectral Analysis';
            app.HeartRateFFTAxes = uiaxes(app.SpectralAnalysisPanel);
            title(app.HeartRateFFTAxes, 'Heart Rate Spectrum')
            xlabel(app.HeartRateFFTAxes, 'Frequency ( HZ ) ')
            ylabel(app.HeartRateFFTAxes, 'Magnitude')
            zlabel(app.HeartRateFFTAxes, 'Z')
            app.RespirationFFTAxes = uiaxes(app.SpectralAnalysisPanel);
            title(app.RespirationFFTAxes, 'Respiration  Spectrum')
            xlabel(app.RespirationFFTAxes, 'Frequency ( HZ ) ')
            ylabel(app.RespirationFFTAxes, 'Magnitude')
            zlabel(app.RespirationFFTAxes, 'Z')
            app.RespirationFFTAxes.ButtonDownFcn = createCallbackFcn(app, @RespirationFFTAxesButtonDown, true);
            app.RunSpectralAnalysisButton = uibutton(app.BionicRFRadarUIFigure, 'push');
            app.RunSpectralAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpectralAnalysisButtonPushed2, true);
            app.RunSpectralAnalysisButton.Text = 'Run Spectral Analysis ';
            app.VitalSignsPanel = uipanel(app.BionicRFRadarUIFigure);
            app.VitalSignsPanel.Title = 'Vital  Signs';
            app.HeartRateBPMLabel = uilabel(app.VitalSignsPanel);
            app.HeartRateBPMLabel.Text = 'Heart Rate (BPM)';
            app.EditField = uieditfield(app.VitalSignsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Editable = 'off';
            app.RespirationRateRPMLabel = uilabel(app.VitalSignsPanel);
            app.RespirationRateRPMLabel.Text = 'Respiration Rate (RPM)';
            app.EditField_2 = uieditfield(app.VitalSignsPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.Editable = 'off';
            app.ExportDataButton = uibutton(app.BionicRFRadarUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data (CSV)';
            % Show the figure after all components are created
            app.BionicRFRadarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bionic_RF_Radar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BionicRFRadarUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BionicRFRadarUIFigure)
        end
    end
end